classdef ExibicaoResultados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        RecalcularmodeloButton  matlab.ui.control.Button
        StatusLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        extRes = struct; % Resultados do anel externo
        esfRes = struct; % Resultados da esfera
        intRes = struct; % Resultados do anel interno
        t = []; % Vetor tempo
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLabel.Text = 'Modelo ainda não calculado.';
        end

        % Button pushed function: RecalcularmodeloButton
        function RecalcularmodeloButtonPushed(app, event)
            app.StatusLabel.Text = 'Pronto.';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RecalcularmodeloButton = uibutton(app.UIFigure, 'push');
            app.RecalcularmodeloButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcularmodeloButtonPushed, true);
            app.RecalcularmodeloButton.Text = 'Recalcular modelo';
            app.StatusLabel = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExibicaoResultados

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end